classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        usefeaturesfromwavCheckBox  matlab.ui.control.CheckBox
        musicplayEditField_2Label   matlab.ui.control.Label
        musicplayEditField_2        matlab.ui.control.EditField
        musicfeatureEditFieldLabel  matlab.ui.control.Label
        musicfeatureEditField       matlab.ui.control.EditField
        GetFeatureButton            matlab.ui.control.Button
        PlayMusicButton             matlab.ui.control.Button
        loadjsonButton              matlab.ui.control.Button
        loadwavButton               matlab.ui.control.Button
        UITable                     matlab.ui.control.Table
        analyseratioEditFieldLabel  matlab.ui.control.Label
        analyseratioEditField       matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        music_json_path string
        music_wav_path string
        harmonic_data 
        ratio double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: usefeaturesfromwavCheckBox
        function usefeaturesfromwavCheckBoxValueChanged(app, event)
            value = app.usefeaturesfromwavCheckBox.Value;
        end

        % Button pushed function: PlayMusicButton
        function PlayMusicButtonPushed(app, event)
            playmode = get(app.usefeaturesfromwavCheckBox, 'Value');
            json_path = app.music_json_path;
            if(playmode)
                [t, song_data] = play_music_with_json_and_harmonic(json_path, app.harmonic_data);
            else
                [t, song_data] = play_music_with_json(json_path);
            end
            plot(app.UIAxes, t, song_data);
        end

        % Value changed function: musicplayEditField_2
        function musicplayEditField_2ValueChanged(app, event)
            value = app.musicplayEditField_2.Value;
        end

        % Button pushed function: loadjsonButton
        function loadjsonButtonPushed(app, event)
            app.music_json_path = get(app.musicplayEditField_2, 'Value');
            if exist(app.music_json_path, 'file')
                msgbox('load json successfully!', 'success', 'modal');
            else
                msgbox('failed to load json!', 'fail', 'modal');
            end
        end

        % Button pushed function: loadwavButton
        function loadwavButtonPushed(app, event)
            app.music_wav_path = get(app.musicfeatureEditField, 'Value');
            if exist(app.music_wav_path, 'file')
                msgbox('load wav successfully!', 'success', 'modal');
            else
                msgbox('failed to load wav!', 'fail', 'modal');
            end
        end

        % Button pushed function: GetFeatureButton
        function GetFeatureButtonPushed(app, event)
            [data, freq] = audioread(app.music_wav_path);
            if(app.ratio == 0)
                app.ratio = 1;
            end
            data = data(1:round(length(data)*app.ratio));
            app.harmonic_data = get_harmonic(data, freq);
            set(app.UITable, 'Data', app.harmonic_data);
            msgbox('get feature successfully', 'modal');
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Value changed function: analyseratioEditField
        function analyseratioEditFieldValueChanged(app, event)
            value = str2double(app.analyseratioEditField.Value);
            if(value > 1)
                app.ratio = 1;
                msgbox('invalid ratio!', 'success', 'modal');
            else
                app.ratio = value;
                msgbox('set ratio successfully!', 'success', 'modal');
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.usefeaturesfromwavCheckBox = uicheckbox(app.UIFigure);
            app.usefeaturesfromwavCheckBox.ValueChangedFcn = createCallbackFcn(app, @usefeaturesfromwavCheckBoxValueChanged, true);
            app.usefeaturesfromwavCheckBox.Text = 'use features from .wav';
            app.musicplayEditField_2Label = uilabel(app.UIFigure);
            app.musicplayEditField_2Label.Text = 'music(play)';
            app.musicplayEditField_2 = uieditfield(app.UIFigure, 'text');
            app.musicplayEditField_2.ValueChangedFcn = createCallbackFcn(app, @musicplayEditField_2ValueChanged, true);
            app.musicfeatureEditFieldLabel = uilabel(app.UIFigure);
            app.musicfeatureEditFieldLabel.Text = 'music(feature)';
            app.musicfeatureEditField = uieditfield(app.UIFigure, 'text');
            app.GetFeatureButton = uibutton(app.UIFigure, 'push');
            app.GetFeatureButton.ButtonPushedFcn = createCallbackFcn(app, @GetFeatureButtonPushed, true);
            app.GetFeatureButton.Text = 'Get Feature';
            app.PlayMusicButton = uibutton(app.UIFigure, 'push');
            app.PlayMusicButton.ButtonPushedFcn = createCallbackFcn(app, @PlayMusicButtonPushed, true);
            app.PlayMusicButton.Text = 'Play Music';
            app.loadjsonButton = uibutton(app.UIFigure, 'push');
            app.loadjsonButton.ButtonPushedFcn = createCallbackFcn(app, @loadjsonButtonPushed, true);
            app.loadjsonButton.Text = 'load .json';
            app.loadwavButton = uibutton(app.UIFigure, 'push');
            app.loadwavButton.ButtonPushedFcn = createCallbackFcn(app, @loadwavButtonPushed, true);
            app.loadwavButton.Text = 'load .wav';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'};
            app.UITable.RowName = {};
            app.analyseratioEditFieldLabel = uilabel(app.UIFigure);
            app.analyseratioEditFieldLabel.Text = 'analyse ratio';
            app.analyseratioEditField = uieditfield(app.UIFigure, 'text');
            app.analyseratioEditField.ValueChangedFcn = createCallbackFcn(app, @analyseratioEditFieldValueChanged, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'music')
            xlabel(app.UIAxes, 'time')
            ylabel(app.UIAxes, 'amplitude')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end